classdef HRsense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HRsenseUIFigure               matlab.ui.Figure
        secLabel                      matlab.ui.control.Label
        ElectromagneticApplicationTechnologyLabLabel  matlab.ui.control.Label
        JejuNationalUniversitymarch2023Label  matlab.ui.control.Label
        bpmLabel                      matlab.ui.control.Label
        Image3                        matlab.ui.control.Image
        EditField_5                   matlab.ui.control.NumericEditField
        SOURCELabel                   matlab.ui.control.Label
        TextArea_3                    matlab.ui.control.TextArea
        Image                         matlab.ui.control.Image
        Image2_2                      matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Switch                        matlab.ui.control.Switch
        Lamp_2                        matlab.ui.control.Lamp
        Lamp                          matlab.ui.control.Lamp
        TextArea_2                    matlab.ui.control.TextArea
        CheckButton                   matlab.ui.control.Button
        RecordButton                  matlab.ui.control.Button
        EditField_4                   matlab.ui.control.EditField
        BackupfilenameLabel           matlab.ui.control.Label
        EditField_3                   matlab.ui.control.NumericEditField
        RecordingtimeLabel            matlab.ui.control.Label
        EditField_2                   matlab.ui.control.EditField
        SerialportNoLabel             matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        FilenameLabel                 matlab.ui.control.Label
        ImportButton                  matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        ChoosefileLabel               matlab.ui.control.Label
        CHECKDEVICESLabel             matlab.ui.control.Label
        RECORDDATALabel               matlab.ui.control.Label
        IMPORTDATALabel               matlab.ui.control.Label
        MODESwitch                    matlab.ui.control.Switch
        MODESwitchLabel               matlab.ui.control.Label
        HeartRateMeasurementAppLabel  matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data_imported % Store the data imported
    end
    
    methods (Access = private)
        
        function ChangeClr(app) % Change the color of the lamp when a procedure is pending
            app.Lamp.Color = 'green';
            pause(0.005);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            app.ChangeClr;            
            % Connection to the instrument 
            info1 = instrhwinfo('serial'); % Retrieve all USB peripherals
            info2 = instrhwinfo('Bluetooth') % Retrieve all Bluetooth peripherals
            app.TextArea_2.Value = [info1.AvailableSerialPorts; info2.RemoteNames]; % Write the peripherals of yhe screen
            app.Lamp.Color = 'red';
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            
            if strcmp(app.MODESwitch.Value, 'Import data') && ~strcmp(app.EditField.Value, '')
                
                %% data load
                RawData = app.data_imported;
                Data = reshape(RawData, 19, length(RawData)/19);  

                %%% Data content %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Data(1,:) = 255
                % Data(2,:) = 254
                % Data(3,:) = PUD_0
                % Data(4,:) = PCD_type, PUD_2, CRD
                % Data(5,:) = PC
                % Data(6,:) = PUD_1
                % Data(7,:) = PCD
                % Data(8,:) = ch1_stream high
                % Data(9,:) = ch1_stream low
                % Data(10:19,:) = 0
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Remove all the zeros
                born_max = size(Data,1);
                born_min = 1,
                
                while Data(born_min, 1) == 0
                    born_min = born_min + 1;
                end
                
                while Data(born_max, 1) == 0
                    born_max = born_max - 1;
                end
                Data = Data(born_min:born_max,:);

                % Reshape dataset
                Packet_Num = size(Data,2);
                count = 0;
                for i=1:32
                    count = count + 1;
                    test = Data(5, i);
                    if test == 31
                        index = count;
                    end
                end
                Data = Data(:, (index+1):Packet_Num);
                
                Packet_Num = size(Data,2);
                count = 0;
                for i=1:32
                    count = count + 1;
                    test = Data(5, Packet_Num+1-i);
                    if test == 0
                        index = count;
                    end
                end
                Data = Data(:, 1:Packet_Num-index);                
                            
                %% Extracting heart rate information per minute 
                
                % 1 Packet cycle time 
                Packet_Num = size(Data,2);          
                Packet_Time = 1/256;               
                PacketCycle_Num = Packet_Num/32;    
                PacketCycle_Time = Packet_Time*32;  
                TotalMeasureTime = PacketCycle_Num*PacketCycle_Time;            
                                
                % BPM time
                tt_BPM = [0:PacketCycle_Time:PacketCycle_Time*(PacketCycle_Num-1)] + PacketCycle_Time; 
                                
                index = [1:32:32*PacketCycle_Num]+2;
                BPM_data = Data(7,index);
                BPM_average = sum(BPM_data,2)/PacketCycle_Num;
                
                %% PPG Information Extraction
                
                % PPG time vector
                tt_PPG = [0:Packet_Time:Packet_Time*(Packet_Num-1)] + Packet_Time;                                                
                PPG = Data(8,:)*256 + Data(9,:);

                %% FFT Analysis
                temp = fft(PPG, [], 2)/Packet_Num;
                temp(1) = 0;
                PPG_FFT = fftshift(temp, 2);

                % freq. axis
                dt = Packet_Time;
                df = 1/dt/Packet_Num;
                ff = [-floor(Packet_Num/2):round(Packet_Num/2)-1]*df;

                %% Results displaying                
                bar(app.UIAxes_2, tt_BPM, BPM_data);
                plot(app.UIAxes_3, tt_PPG, PPG);                
                plot(app.UIAxes, ff*60, abs(PPG_FFT));
                
                %% Print the heart value in the screen
                app.EditField_5.Value = ceil(mean(BPM_data));          

            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if strcmp(app.MODESwitch.Value, 'Import data')
                [file, path, indx] = uigetfile;
                filename = [path file];
                temp = struct2cell(load(filename));
                app.data_imported = temp{1,1};
                app.EditField.Value = file;
            end
        end

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            if strcmp(app.MODESwitch.Value, 'New measurement')

                app.Lamp_2.Color = 'green';
                pause(0.005);
                MeasureTime = app.EditField_3.Value;  % [sec]
                serialportlist("available")        
                serialportObj = serialport(app.EditField_2.Value, 115200); 
                RawData = read(serialportObj,(256*19)*floor(MeasureTime)+64*19,"uint8"); 
                clear serialportObj;
                Data = reshape(RawData, 19, length(RawData)/19);  
                save(strcat(app.EditField_4.Value, '.mat'), 'RawData');

                %%% Data 구조 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % Data(1,:) = 255
                % Data(2,:) = 254
                % Data(3,:) = PUD_0
                % Data(4,:) = PCD_type, PUD_2, CRD
                % Data(5,:) = PC
                % Data(6,:) = PUD_1
                % Data(7,:) = PCD
                % Data(8,:) = ch1_stream high
                % Data(9,:) = ch1_stream low
                % Data(10:8,:) = 0
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                if strcmp(app.Switch.Value, 'BT')
                % Remove all the zeros
                Data = Data';
                Data = circshift(Data, 1-find(Data==255));
                Data = Data';
                born_max = size(Data,1);
                born_min = 1,
                
                while Data(born_min, 1) == 0
                    born_min = born_min + 1;
                end
                
                while Data(born_max, 1) == 0
                    born_max = born_max - 1;
                end
                Data = Data(born_min:born_max,:);
                end
                
                Packet_Num = size(Data,2);
                count = 0;
                for i=1:32
                    count = count + 1;
                    test = Data(5, i);
                    if test == 31
                        index = count;
                    end
                end
                Data = Data(:, (index+1):Packet_Num);
                
                Packet_Num = size(Data,2);
                count = 0;
                for i=1:32
                    count = count + 1;
                    test = Data(5, Packet_Num+1-i);
                    if test == 0
                        index = count;
                    end
                end
                Data = Data(:, 1:Packet_Num-index);

                %% Extract heart rate information per minute
                
                % 1 Packet cycle time    --->   Total observation time
                Packet_Num = size(Data,2);        % Total number of packets during the total observation time
                PacketCycle_Num = Packet_Num/32;  %  Total packet cycles
                PacketCycle_time = (1/256)*32;    
                
                %  Determining if it is an integer
                IsInteger = mod(PacketCycle_Num,1)==0;  % Typically, it should be an integer, but if the data is dropped in the middle, it is not an integer
                                
                % Extract heart rate information per minute
                if IsInteger==1
                    TotalMeasureTime = PacketCycle_Num*PacketCycle_time;
                    tt_BPM = [0:PacketCycle_time:PacketCycle_time*(PacketCycle_Num-1)] + PacketCycle_time;
                
                    % When PCD value is heart rate per minute
                    index = [1:32:32*PacketCycle_Num]+2;
                    BPM_data = Data(7,index);
                    BPM_average = sum(BPM_data,2)/PacketCycle_Num;
                else
                    index = [];
                    %  When PCD value is heart rate per minute
                    for i=1:Packet_Num
                        if Data(5,i)==2
                            index = [index, i];
                        end
                    end
                    
                    BPM_data = Data(7,index);    
                    tt_BPM_temp = [0:(1/256):(1/256)*(Packet_Num-1)];
                    tt_BPM = tt_BPM_temp(index);    
                    BPM_average = sum(BPM_data,2)/length(tt_BPM);
                    TotalMeasureTime = tt_BPM(end);
                    disp('Packet 손실 발생');
                end  
                
                %% Extracting PPG information
                
                % PPG time축 생성(creation)
                tt_PPG = [0:(1/256):(1/256)*(Packet_Num-1)] + (1/256);
       
                %  Extracting information
                PPG_Extract_Type = 2;  
                switch PPG_Extract_Type
                    case 1
                        PPG = zeros(1,Packet_Num);
                        for i=1:Packet_Num  % Packet_Num
                            temp_high = Data(8,i);
                            temp_low = Data(9,i);
                            
                            temp_high_4bit = dec2bin(temp_high, 8);
                            temp_low_8bit = dec2bin(temp_low, 8);
                            
                            temp_bin = [temp_high_4bit(5) temp_high_4bit(6) temp_high_4bit(7) temp_high_4bit(8) ...
                                temp_low_8bit(1) temp_low_8bit(2) temp_low_8bit(3) temp_low_8bit(4) ...
                                temp_low_8bit(5) temp_low_8bit(6) temp_low_8bit(7) temp_low_8bit(8)];
                            
                            PPG(i) = bin2dec(temp_bin);
                        end
                        
                        
                    case 2
                        PPG = Data(8,:)*256 + Data(9,:);
                end

                % PPG FFT analysis
                temp = fft(PPG, [], 2)/Packet_Num;
                temp(1) = 0;  
                PPG_FFT = fftshift(temp, 2);
                
                Packet_Time = 1/256;
                
                % freq. axis 
                dt = Packet_Time;
                df = 1/dt/Packet_Num;
                ff = [-floor(Packet_Num/2):round(Packet_Num/2)-1]*df;

                %% Results displaying
                plot(app.UIAxes_2, tt_BPM, BPM_data);                
                plot(app.UIAxes_3, tt_PPG, PPG);
                plot(app.UIAxes, ff*60, abs(PPG_FFT));
                
                %% Print the heart value in the screen
                app.EditField_5.Value = ceil(mean(BPM_data));                
                app.Lamp_2.Color = 'red';
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HRsenseUIFigure = uifigure('Visible', 'off');
            app.HRsenseUIFigure.Name = 'HRsense';
            app.HRsenseUIFigure.Icon = fullfile(pathToMLAPP, 'logo.PNG');
            app.UIAxes = uiaxes(app.HRsenseUIFigure);
            title(app.UIAxes, 'Spectrum Analysis')
            xlabel(app.UIAxes, 'Frequency [BPM]')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-200 200];
            app.UIAxes_2 = uiaxes(app.HRsenseUIFigure);
            title(app.UIAxes_2, 'Heart Rate Evolution')
            xlabel(app.UIAxes_2, 'time [sec]')
            ylabel(app.UIAxes_2, 'Heart Rate [BPM]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.YLim = [0 120];
            app.UIAxes_3 = uiaxes(app.HRsenseUIFigure);
            title(app.UIAxes_3, 'Sensor Measurement')
            xlabel(app.UIAxes_3, 'time [sec]')
            ylabel(app.UIAxes_3, 'Amplitude')
            zlabel(app.UIAxes_3, 'Z')
            app.TextArea = uitextarea(app.HRsenseUIFigure);
            app.HeartRateMeasurementAppLabel = uilabel(app.HRsenseUIFigure);
            app.HeartRateMeasurementAppLabel.Text = 'Heart Rate Measurement App';
            app.MODESwitchLabel = uilabel(app.HRsenseUIFigure);
            app.MODESwitchLabel.Text = 'MODE';
            app.MODESwitch = uiswitch(app.HRsenseUIFigure, 'slider');
            app.MODESwitch.Items = {'Import data', 'New measurement'};
            app.MODESwitch.Value = 'Import data';
            app.IMPORTDATALabel = uilabel(app.HRsenseUIFigure);
            app.IMPORTDATALabel.Text = 'IMPORT DATA';
            app.RECORDDATALabel = uilabel(app.HRsenseUIFigure);
            app.RECORDDATALabel.Text = 'RECORD DATA';
            app.CHECKDEVICESLabel = uilabel(app.HRsenseUIFigure);
            app.CHECKDEVICESLabel.Text = 'CHECK DEVICES';
            app.ChoosefileLabel = uilabel(app.HRsenseUIFigure);
            app.ChoosefileLabel.Text = 'Choose file';
            app.Button = uibutton(app.HRsenseUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'import.png');
            app.Button.Text = '';
            app.ImportButton = uibutton(app.HRsenseUIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameLabel = uilabel(app.HRsenseUIFigure);
            app.FilenameLabel.Text = 'File name';
            app.EditField = uieditfield(app.HRsenseUIFigure, 'text');
            app.EditField.Placeholder = 'leave it blank';
            app.SerialportNoLabel = uilabel(app.HRsenseUIFigure);
            app.SerialportNoLabel.Text = 'Serial port No.';
            app.EditField_2 = uieditfield(app.HRsenseUIFigure, 'text');
            app.EditField_2.Value = 'COM';
            app.RecordingtimeLabel = uilabel(app.HRsenseUIFigure);
            app.RecordingtimeLabel.Text = 'Recording time';
            app.EditField_3 = uieditfield(app.HRsenseUIFigure, 'numeric');
            app.EditField_3.Value = 26;
            app.BackupfilenameLabel = uilabel(app.HRsenseUIFigure);
            app.BackupfilenameLabel.Text = 'Backup file name';
            app.EditField_4 = uieditfield(app.HRsenseUIFigure, 'text');
            app.EditField_4.Value = 'data';
            app.RecordButton = uibutton(app.HRsenseUIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.CheckButton = uibutton(app.HRsenseUIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.TextArea_2 = uitextarea(app.HRsenseUIFigure);
            app.TextArea_2.Placeholder = 'Check all USB and bluetooth peripherals connected';
            app.Lamp = uilamp(app.HRsenseUIFigure);
            app.Lamp_2 = uilamp(app.HRsenseUIFigure);
            app.Switch = uiswitch(app.HRsenseUIFigure, 'slider');
            app.Switch.Items = {'BT', 'USB'};
            app.Switch.Value = 'BT';
            app.Image2 = uiimage(app.HRsenseUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'bt-icon.png');
            app.Image2_2 = uiimage(app.HRsenseUIFigure);
            app.Image2_2.ImageSource = fullfile(pathToMLAPP, 'usb-icon.png');
            app.Image = uiimage(app.HRsenseUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'HRsense.png');
            app.TextArea_3 = uitextarea(app.HRsenseUIFigure);
            app.TextArea_3.Value = {'V1.0'};
            app.SOURCELabel = uilabel(app.HRsenseUIFigure);
            app.SOURCELabel.Text = 'SOURCE';
            app.EditField_5 = uieditfield(app.HRsenseUIFigure, 'numeric');
            app.Image3 = uiimage(app.HRsenseUIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            app.bpmLabel = uilabel(app.HRsenseUIFigure);
            app.bpmLabel.Text = 'bpm';
            app.JejuNationalUniversitymarch2023Label = uilabel(app.HRsenseUIFigure);
            app.JejuNationalUniversitymarch2023Label.Text = 'Jeju National University, march 2023.';
            app.ElectromagneticApplicationTechnologyLabLabel = uilabel(app.HRsenseUIFigure);
            app.ElectromagneticApplicationTechnologyLabLabel.Text = 'Electromagnetic Application Technology Lab.';
            app.secLabel = uilabel(app.HRsenseUIFigure);
            app.secLabel.Text = 'sec';
            % Show the figure after all components are created
            app.HRsenseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HRsense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HRsenseUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HRsenseUIFigure)
        end
    end
end